classdef LinkBudgetCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LinkBudgetDisplayUIFigure      matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        configuration_panel            matlab.ui.container.Panel
        TrasmittedfrequencyGHzLabel    matlab.ui.control.Label
        freq                           matlab.ui.control.NumericEditField
        ImplementaionErrordBEditFieldLabel  matlab.ui.control.Label
        im_error                       matlab.ui.control.NumericEditField
        LinkmargindBEditFieldLabel     matlab.ui.control.Label
        margin                         matlab.ui.control.NumericEditField
        SatellitesheightkmEditFieldLabel  matlab.ui.control.Label
        height                         matlab.ui.control.NumericEditField
        ParameterSelectionLabel        matlab.ui.control.Label
        reciever_panel                 matlab.ui.container.Panel
        AntennaBWdegEditField_2Label   matlab.ui.control.Label
        R_theta3db                     matlab.ui.control.NumericEditField
        AntennaMisalignmentdegLabel    matlab.ui.control.Label
        R_theta_misalign               matlab.ui.control.NumericEditField
        GTdBKelvinLabel                matlab.ui.control.Label
        G_over_T                       matlab.ui.control.NumericEditField
        transmiter_panel               matlab.ui.container.Panel
        AntennaGaindBiLabel            matlab.ui.control.Label
        T_Gmax                         matlab.ui.control.NumericEditField
        OutputPowerdBWEditFieldLabel   matlab.ui.control.Label
        T_Prx                          matlab.ui.control.NumericEditField
        AntennaBWdegEditFieldLabel     matlab.ui.control.Label
        T_theta3db                     matlab.ui.control.NumericEditField
        AntennaMisalignmentdegLabel_2  matlab.ui.control.Label
        T_theta_misalign               matlab.ui.control.NumericEditField
        EIRPdBWEditFieldLabel          matlab.ui.control.Label
        T_EIRP                         matlab.ui.control.NumericEditField
        AntennalossesdBEditFieldLabel  matlab.ui.control.Label
        T_Lftx                         matlab.ui.control.NumericEditField
        ProtocolSettingsLabel          matlab.ui.control.Label
        protocol_panel                 matlab.ui.container.Panel
        InitialElevationdegEditFieldLabel  matlab.ui.control.Label
        InitialElevationdeg            matlab.ui.control.NumericEditField
        InitialModcodDropDownLabel     matlab.ui.control.Label
        InitialModcodDropDown          matlab.ui.control.DropDown
        ElevationstepdegLabel          matlab.ui.control.Label
        MinimumElevationstep           matlab.ui.control.NumericEditField
        protocolSelectionButton        matlab.ui.container.ButtonGroup
        DVBS2Button                    matlab.ui.control.RadioButton
        DVBS2XButton                   matlab.ui.control.RadioButton
        RollofffactorEditFieldLabel    matlab.ui.control.Label
        roll_off                       matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        SymbolRateMBdLabel             matlab.ui.control.Label
        SymbolRateEditField            matlab.ui.control.NumericEditField
        OccupiedbandwidthMHzEditFieldLabel  matlab.ui.control.Label
        OccupiedbandwidthEditField     matlab.ui.control.NumericEditField
        AvgDataBitRateMbitsLabel       matlab.ui.control.Label
        AvgBitRateEditField            matlab.ui.control.NumericEditField
        VisibilityTimeMinLabel         matlab.ui.control.Label
        VisibilityTimeEditField        matlab.ui.control.NumericEditField
        TotalDataTransferMbitLabel     matlab.ui.control.Label
        TotalDataTransfer              matlab.ui.control.NumericEditField
        TabGroup                       matlab.ui.container.TabGroup
        PlotTab                        matlab.ui.container.Tab
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ByElevationButton              matlab.ui.control.RadioButton
        ByTimeButton                   matlab.ui.control.RadioButton
        ModcodSelection                matlab.ui.container.ButtonGroup
        ElevationDegEditFieldLabel     matlab.ui.control.Label
        ElevationDegEditField          matlab.ui.control.NumericEditField
        TimeMinEditFieldLabel          matlab.ui.control.Label
        TimeMinEditField               matlab.ui.control.NumericEditField
        SpectralEfficiencyEditFieldLabel  matlab.ui.control.Label
        SpectralEfficiencyEditField    matlab.ui.control.NumericEditField
        MODCODEditFieldLabel           matlab.ui.control.Label
        MODCODEditField                matlab.ui.control.EditField
        InspectElevationSliderLabel    matlab.ui.control.Label
        InspectElevationSlider         matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
        SheetTab                       matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        OverallanalysisLabel           matlab.ui.control.Label
        FileMenu                       matlab.ui.container.Menu
        ExportResultstoCSVMenu         matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        Data
        SelectedGenders
        SelectedColors
        BinWidth
        Histogram = gobjects(0)
        displayedIndices
        
        % Protocol Params
        DVB_s2_names
        DVB_s2_data
        DVB_s2x_names
        DVB_s2x_data
        MODCODS
        MODCOD_names
        
        % Plot vars
        line_plot
        data_table
        
        % Results Vecs
        res_elevation_vec
        res_modcod_idx_vec
        res_modcod_eff_vec
        res_t_vec
        res_Rs
        res_bandwidth
        res_avg_bitrate
        res_total_bits
    end
    
    methods (Access = private)


        function populateModcodDropdown(app)
            if app.DVBS2Button.Value
                app.MODCODS = app.DVB_s2_data;
                app.MODCOD_names = app.DVB_s2_names;
                app.InitialModcodDropDown.Items = app.DVB_s2_names;
                app.InitialModcodDropDown.ItemsData = 1:numel(app.DVB_s2_names);
                app.InitialModcodDropDown.Value = 4;
            elseif app.DVBS2XButton.Value
                app.MODCODS = app.DVB_s2x_data;
                app.MODCOD_names = app.DVB_s2x_names;
                app.InitialModcodDropDown.Items = app.DVB_s2x_names;
                app.InitialModcodDropDown.ItemsData = 1:numel(app.DVB_s2x_names);
                app.InitialModcodDropDown.Value = 4;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            load('data.mat','data_DVB_s2_data','data_DVB_s2_names','data_DVB_s2x_data','data_DVB_s2x_names');
            
            % Store the data
            app.DVB_s2_data = data_DVB_s2_data;
            app.DVB_s2_names = data_DVB_s2_names;
            app.DVB_s2x_data = data_DVB_s2x_data;
            app.DVB_s2x_names = data_DVB_s2x_names;
          
            % Update the axes with the corresponding data.
            populateModcodDropdown(app)
            parametersFieldChanged(app)
            InspectElevationSliderValueChanged(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LinkBudgetDisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {847, 847};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {475, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: G_over_T, InitialElevationdeg, 
        % InitialModcodDropDown, MinimumElevationstep, R_theta3db, 
        % R_theta_misalign, T_EIRP, T_Gmax, T_Lftx, T_Prx, 
        % T_theta3db, T_theta_misalign, freq, height, im_error, 
        % margin, roll_off
        function parametersFieldChanged(app, event)
            % calc EIRP
            %app.T_Lftx.Value = app.T_Prx.Value + app.T_Gmax.Value - app.T_EIRP.Value;
            app.T_EIRP.Value = app.T_Prx.Value + app.T_Gmax.Value - app.T_Lftx.Value ;
            
            % find best MODCOD for each degree
            [app.res_elevation_vec, app.res_modcod_idx_vec, app.res_modcod_eff_vec, app.res_t_vec, app.res_Rs, app.res_bandwidth] ...
                    = findModcods( app.T_Prx.Value , app.T_Gmax.Value , app.T_Lftx.Value , app.T_theta3db.Value , ...
                      app.T_theta_misalign.Value, app.InitialElevationdeg.Value, app.MinimumElevationstep.Value, ...
                      app.freq.Value , app.height.Value , app.R_theta3db.Value , app.R_theta_misalign.Value , ...
                      app.G_over_T.Value, app.MODCODS, app.InitialModcodDropDown.Value, app.roll_off.Value, app.im_error.Value, app.margin.Value);
            
            % Calculate average bit rate
            app.res_avg_bitrate = app.res_Rs * sum(diff( app.res_t_vec )' .* app.res_modcod_eff_vec(1:end-1)) / app.res_t_vec(end);
            app.res_total_bits = app.res_avg_bitrate * app.res_t_vec(end) * 60 * 2;
            
            % refresh
            refreshResults(app)
        end

        % Selection changed function: protocolSelectionButton
        function protocolSelectionButtonSelectionChanged(app, event)
            populateModcodDropdown(app)
            parametersFieldChanged(app)
        end

        % Selection changed function: ButtonGroup
        function refreshResults(app, event)
            
            %--- Number Results ---
            app.SymbolRateEditField.Value = app.res_Rs;
            app.OccupiedbandwidthEditField.Value = app.res_bandwidth;
            app.VisibilityTimeEditField.Value = app.res_t_vec(end)*2;
            app.AvgBitRateEditField.Value = app.res_avg_bitrate;
            app.TotalDataTransfer.Value = app.res_total_bits;
            
            %--- Plot ---
            %Start with a fresh plot
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            
            ydata = [app.res_modcod_eff_vec; flipud(app.res_modcod_eff_vec(1:end-1)) ];
            app.UIAxes.YLabel.String = 'Spectral Efficiency [Data Bits/Symbol]';
            
            if app.ByElevationButton.Value
                xdata = [app.res_elevation_vec , 2*app.res_elevation_vec(end)-fliplr(app.res_elevation_vec(1:end-1)) ];
                app.UIAxes.XLabel.String = 'Elevation [deg]';
                app.UIAxes.Title.String = 'Spectral Efficiency by Elevation Degree';
                app.InspectElevationSliderLabel.Text = 'Inspect Elevation';
                app.UIAxes.XLim = [0 180];
              
            elseif app.ByTimeButton.Value
                xdata = [app.res_t_vec, 2*app.res_t_vec(end)-fliplr(app.res_t_vec(1:end-1))];
                app.UIAxes.XLabel.String = 'Time [min]';
                app.UIAxes.Title.String = 'Spectral Efficiency by Time';
                app.InspectElevationSliderLabel.Text = 'Inspect Time';
                app.UIAxes.XLim = [xdata(1) xdata(end)];
                
            end
            
            app.InspectElevationSlider.Limits = [xdata(1) xdata(end)];
            app.InspectElevationSlider.Value = xdata(1);
            app.line_plot = xline(app.UIAxes, xdata(1), '--r');
            stairs(app.UIAxes, xdata, ydata,'.-','MarkerSize',10,'LineWidth', 2)
           
            % --- Table ---
            app.data_table =table(app.res_elevation_vec',app.res_t_vec',app.MODCOD_names(app.res_modcod_idx_vec)', app.res_modcod_eff_vec,...
                              'VariableNames',{'Elevation [deg]','Time [Min]','Modcod','Spectral Efficiency'} );
            app.UITable.Data=app.data_table;
            
            drawnow;
        end

        % Value changed function: InspectElevationSlider
        function InspectElevationSliderValueChanged(app, event)
            % qauntize slider
            value = app.InspectElevationSlider.Value;
            
            full_elevation_vec = [app.res_elevation_vec , 2*app.res_elevation_vec(end)-fliplr(app.res_elevation_vec(1:end-1)) ]; 
            full_time_vec = [app.res_t_vec, 2*app.res_t_vec(end)-fliplr(app.res_t_vec(1:end-1))];
            
            if app.ByElevationButton.Value
                xdata = full_elevation_vec;
            elseif app.ByTimeButton.Value
                xdata = full_time_vec;
            end
            
            [~,arg_idx] = min(abs(xdata - value));
            value = xdata(arg_idx);
            app.InspectElevationSlider.Value = value;
            
            % update plot
            app.line_plot.Value = value;
            
            % update results
            vec_len = length(app.res_elevation_vec);
            if (vec_len < arg_idx)
                left_arg_idx = 2*vec_len - arg_idx;
            else
                left_arg_idx = arg_idx;
            end
            
            app.ElevationDegEditField.Value = full_elevation_vec(arg_idx);
            app.TimeMinEditField.Value = full_time_vec(arg_idx);
            app.SpectralEfficiencyEditField.Value = app.res_modcod_eff_vec(left_arg_idx);
            app.MODCODEditField.Value = app.MODCOD_names{app.res_modcod_idx_vec(left_arg_idx)};
        end

        % Menu selected function: ExportResultstoCSVMenu
        function ExporttoCSVButtonPushed(app, event)
            [file,path] = uiputfile('*.csv', 'Select Location', 'Data');
            if file ~= 0
                filename = fullfile(path,file);
                writetable(app.data_table, filename);
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            T_lftx = app.T_Lftx.Value;
            T_EIRP = app.T_EIRP.Value;
            T_theta_misalign = app.T_theta_misalign.Value;
            T_theta_3db = app.T_theta3db.Value;
            T_Prx = app.T_Prx.Value;
            T_Gmax = app.T_Gmax.Value;
            G_over_T = app.G_over_T.Value;
            R_theta_misalign = app.R_theta_misalign.Value;
            R_theta_3db = app.R_theta3db.Value;
            height = app.height.Value;
            roll_off = app.roll_off.Value;
            margin = app.margin.Value;
            im_error = app.im_error.Value;
            freq = app.freq.Value;
            step = app.MinimumElevationstep.Value;
            initial_elevation = app.InitialElevationdeg.Value;
            selected_modcod = app.InitialModcodDropDown.Value;
            
            if app.DVBS2Button.Value
                protocol = 'DVB_s2';
            elseif app.DVBS2XButton.Value
                protocol = 'DVB_s2x';
            end
            
            [file,path] = uiputfile('*.mat', 'Select Location', 'Params');
            if file ~= 0
                filename = fullfile(path,file);
                save(filename, 'T_lftx','T_EIRP','T_theta_misalign','T_theta_3db','T_Prx','T_Gmax',...
                    'G_over_T','R_theta_misalign','R_theta_3db','height','roll_off' , 'margin', ...
                    'im_error','freq','step','initial_elevation', 'protocol', 'selected_modcod');
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            try
                [file,path] = uigetfile('*.mat', 'Select Location', 'Params');
                if file ~= 0
                    filename = fullfile(path,file);
                    params = load(filename);
                    
                    app.T_Lftx.Value=params.T_lftx ;
                    app.T_EIRP.Value=params.T_EIRP ;
                    app.T_theta_misalign.Value=params.T_theta_misalign ;
                    app.T_theta3db.Value=params.T_theta_3db ;
                    app.T_Prx.Value=params.T_Prx ;
                    app.T_Gmax.Value=params.T_Gmax ;
                    app.G_over_T.Value=params.G_over_T ;
                    app.R_theta_misalign.Value=params.R_theta_misalign ;
                    app.R_theta3db.Value=params.R_theta_3db ;
                    app.height.Value=params.height ;
                    app.roll_off.Value=params.roll_off ;
                    app.margin.Value=params.margin ;
                    app.im_error.Value=params.im_error ;
                    app.freq.Value=params.freq ;                
                    app.MinimumElevationstep.Value=params.step ;
                    app.InitialElevationdeg.Value=params.initial_elevation ;
                    
                    if strcmp(params.protocol, 'DVB_s2')
                        app.DVBS2Button.Value = 1;
                    elseif strcmp(params.protocol, 'DVB_s2x')
                        app.DVBS2XButton.Value = 1;
                    end
                    populateModcodDropdown(app);
                    app.InitialModcodDropDown.Value = params.selected_modcod;
                    
                    parametersFieldChanged(app);
                end
            
            catch
                errordlg('The Selected File is Corrupted','Loading Error');
            end 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LinkBudgetDisplayUIFigure = uifigure('Visible', 'off');
            app.LinkBudgetDisplayUIFigure.Name = 'Link Budget Calculator';
            app.LinkBudgetDisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LinkBudgetDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.configuration_panel = uipanel(app.LeftPanel);
            app.configuration_panel.Title = 'Configurations';
            app.TrasmittedfrequencyGHzLabel = uilabel(app.configuration_panel);
            app.TrasmittedfrequencyGHzLabel.Text = 'Trasmitted frequency [GHz]';
            app.freq = uieditfield(app.configuration_panel, 'numeric');
            app.freq.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.freq.Tooltip = {'Trasmited frequency'};
            app.freq.Value = 26.8;
            app.ImplementaionErrordBEditFieldLabel = uilabel(app.configuration_panel);
            app.ImplementaionErrordBEditFieldLabel.Text = 'Implementaion Error [dB]';
            app.im_error = uieditfield(app.configuration_panel, 'numeric');
            app.im_error.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.im_error.Tooltip = {'Implementaion Error'};
            app.im_error.Value = 1.5;
            app.LinkmargindBEditFieldLabel = uilabel(app.configuration_panel);
            app.LinkmargindBEditFieldLabel.Text = 'Link margin [dB]';
            app.margin = uieditfield(app.configuration_panel, 'numeric');
            app.margin.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.margin.Tooltip = {'Link margin'};
            app.margin.Value = 6;
            app.SatellitesheightkmEditFieldLabel = uilabel(app.configuration_panel);
            app.SatellitesheightkmEditFieldLabel.Text = 'Satellite''s height [km]';
            app.height = uieditfield(app.configuration_panel, 'numeric');
            app.height.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.height.Tooltip = {'Satellite''s height relative to the earth''s surface'};
            app.height.Value = 800;
            app.ParameterSelectionLabel = uilabel(app.LeftPanel);
            app.ParameterSelectionLabel.Text = 'Parameter Selection';
            app.reciever_panel = uipanel(app.LeftPanel);
            app.reciever_panel.Title = 'Receiver';
            app.AntennaBWdegEditField_2Label = uilabel(app.reciever_panel);
            app.AntennaBWdegEditField_2Label.Text = 'Antenna BW [deg]';
            app.R_theta3db = uieditfield(app.reciever_panel, 'numeric');
            app.R_theta3db.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.R_theta3db.Tooltip = {'Reciever Half Power Bandwidth'};
            app.R_theta3db.Value = 0.28;
            app.AntennaMisalignmentdegLabel = uilabel(app.reciever_panel);
            app.AntennaMisalignmentdegLabel.Text = 'Antenna Misalignment [deg]';
            app.R_theta_misalign = uieditfield(app.reciever_panel, 'numeric');
            app.R_theta_misalign.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.R_theta_misalign.Tooltip = {'Reciever maximum misalignment angle'};
            app.R_theta_misalign.Value = 0.03;
            app.GTdBKelvinLabel = uilabel(app.reciever_panel);
            app.GTdBKelvinLabel.Text = 'G / T [dB /Kelvin]';
            app.G_over_T = uieditfield(app.reciever_panel, 'numeric');
            app.G_over_T.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.G_over_T.Tooltip = {'Reciever figure of merit'};
            app.G_over_T.Value = 28.5;
            app.transmiter_panel = uipanel(app.LeftPanel);
            app.transmiter_panel.Title = 'Transmitter';
            app.AntennaGaindBiLabel = uilabel(app.transmiter_panel);
            app.AntennaGaindBiLabel.Text = 'Antenna Gain [dBi]';
            app.T_Gmax = uieditfield(app.transmiter_panel, 'numeric');
            app.T_Gmax.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.T_Gmax.Tooltip = {'Transmitter Antena Gain'};
            app.T_Gmax.Value = 23.5;
            app.OutputPowerdBWEditFieldLabel = uilabel(app.transmiter_panel);
            app.OutputPowerdBWEditFieldLabel.Text = 'Output Power [dBW]';
            app.T_Prx = uieditfield(app.transmiter_panel, 'numeric');
            app.T_Prx.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.T_Prx.Tooltip = {'Transmiter Output Power'};
            app.T_Prx.Value = -2.218;
            app.AntennaBWdegEditFieldLabel = uilabel(app.transmiter_panel);
            app.AntennaBWdegEditFieldLabel.Text = 'Antenna BW [deg]';
            app.T_theta3db = uieditfield(app.transmiter_panel, 'numeric');
            app.T_theta3db.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.T_theta3db.Tooltip = {'Transmiter Half Power Bandwidth'};
            app.T_theta3db.Value = 10.2;
            app.AntennaMisalignmentdegLabel_2 = uilabel(app.transmiter_panel);
            app.AntennaMisalignmentdegLabel_2.Text = 'Antenna Misalignment [deg]';
            app.T_theta_misalign = uieditfield(app.transmiter_panel, 'numeric');
            app.T_theta_misalign.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.T_theta_misalign.Tooltip = {'Transmiter maximum misalignment angle'};
            app.T_theta_misalign.Value = 1;
            app.EIRPdBWEditFieldLabel = uilabel(app.transmiter_panel);
            app.EIRPdBWEditFieldLabel.Text = 'EIRP [dBW]';
            app.T_EIRP = uieditfield(app.transmiter_panel, 'numeric');
            app.T_EIRP.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.T_EIRP.Editable = 'off';
            app.T_EIRP.Tooltip = {'Transmiter EIRP calc as (Antenna_gain + Output_Power  - Antenna_losses)'};
            app.T_EIRP.Value = 20;
            app.AntennalossesdBEditFieldLabel = uilabel(app.transmiter_panel);
            app.AntennalossesdBEditFieldLabel.Text = 'Antenna losses [dB]';
            app.T_Lftx = uieditfield(app.transmiter_panel, 'numeric');
            app.T_Lftx.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.T_Lftx.Tooltip = {'Transmiter to Antena losses'};
            app.T_Lftx.Value = 1.282;
            app.ProtocolSettingsLabel = uilabel(app.LeftPanel);
            app.ProtocolSettingsLabel.Text = 'Protocol Settings';
            app.protocol_panel = uipanel(app.LeftPanel);
            app.protocol_panel.Title = 'Initial Conditions';
            app.InitialElevationdegEditFieldLabel = uilabel(app.protocol_panel);
            app.InitialElevationdegEditFieldLabel.Text = 'Initial Elevation [deg]';
            app.InitialElevationdeg = uieditfield(app.protocol_panel, 'numeric');
            app.InitialElevationdeg.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.InitialElevationdeg.Tooltip = {'Minimum Elevation degree to start transmission.'};
            app.InitialElevationdeg.Value = 10;
            app.InitialModcodDropDownLabel = uilabel(app.protocol_panel);
            app.InitialModcodDropDownLabel.Text = 'Initial Modcod';
            app.InitialModcodDropDown = uidropdown(app.protocol_panel);
            app.InitialModcodDropDown.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.ElevationstepdegLabel = uilabel(app.protocol_panel);
            app.ElevationstepdegLabel.Text = 'Elevation step [deg]';
            app.MinimumElevationstep = uieditfield(app.protocol_panel, 'numeric');
            app.MinimumElevationstep.Limits = [0.001 90];
            app.MinimumElevationstep.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.MinimumElevationstep.Tooltip = {'find the optimal modcod each elevation step degree'};
            app.MinimumElevationstep.Value = 1;
            app.protocolSelectionButton = uibuttongroup(app.protocol_panel);
            app.protocolSelectionButton.SelectionChangedFcn = createCallbackFcn(app, @protocolSelectionButtonSelectionChanged, true);
            app.DVBS2Button = uiradiobutton(app.protocolSelectionButton);
            app.DVBS2Button.Text = 'DVB-S2';
            app.DVBS2Button.Value = true;
            app.DVBS2XButton = uiradiobutton(app.protocolSelectionButton);
            app.DVBS2XButton.Text = 'DVB-S2X';
            app.RollofffactorEditFieldLabel = uilabel(app.protocol_panel);
            app.RollofffactorEditFieldLabel.Text = 'Roll off factor';
            app.roll_off = uieditfield(app.protocol_panel, 'numeric');
            app.roll_off.Limits = [0 1];
            app.roll_off.ValueChangedFcn = createCallbackFcn(app, @parametersFieldChanged, true);
            app.roll_off.Tooltip = {'Roll off factor'};
            app.roll_off.Value = 0.2;
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.SymbolRateMBdLabel = uilabel(app.Panel);
            app.SymbolRateMBdLabel.Text = 'Symbol Rate [MBd]';
            app.SymbolRateEditField = uieditfield(app.Panel, 'numeric');
            app.SymbolRateEditField.Editable = 'off';
            app.OccupiedbandwidthMHzEditFieldLabel = uilabel(app.Panel);
            app.OccupiedbandwidthMHzEditFieldLabel.Text = 'Occupied bandwidth  [MHz]';
            app.OccupiedbandwidthEditField = uieditfield(app.Panel, 'numeric');
            app.OccupiedbandwidthEditField.Editable = 'off';
            app.AvgDataBitRateMbitsLabel = uilabel(app.Panel);
            app.AvgDataBitRateMbitsLabel.Text = 'Avg Data Bit Rate [Mbit/s]';
            app.AvgBitRateEditField = uieditfield(app.Panel, 'numeric');
            app.AvgBitRateEditField.Editable = 'off';
            app.VisibilityTimeMinLabel = uilabel(app.Panel);
            app.VisibilityTimeMinLabel.Text = 'Visibility Time [Min]';
            app.VisibilityTimeEditField = uieditfield(app.Panel, 'numeric');
            app.VisibilityTimeEditField.Editable = 'off';
            app.TotalDataTransferMbitLabel = uilabel(app.Panel);
            app.TotalDataTransferMbitLabel.Text = 'Total Data Transfer [Mbit]';
            app.TotalDataTransfer = uieditfield(app.Panel, 'numeric');
            app.TotalDataTransfer.Editable = 'off';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.ButtonGroup = uibuttongroup(app.PlotTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @refreshResults, true);
            app.ButtonGroup.Title = 'Plotting Options';
            app.ByElevationButton = uiradiobutton(app.ButtonGroup);
            app.ByElevationButton.Text = 'By Elevation';
            app.ByElevationButton.Value = true;
            app.ByTimeButton = uiradiobutton(app.ButtonGroup);
            app.ByTimeButton.Text = 'By Time';
            app.ModcodSelection = uibuttongroup(app.PlotTab);
            app.ElevationDegEditFieldLabel = uilabel(app.ModcodSelection);
            app.ElevationDegEditFieldLabel.Text = 'Elevation [Deg]';
            app.ElevationDegEditField = uieditfield(app.ModcodSelection, 'numeric');
            app.ElevationDegEditField.Editable = 'off';
            app.TimeMinEditFieldLabel = uilabel(app.ModcodSelection);
            app.TimeMinEditFieldLabel.Text = 'Time [Min]';
            app.TimeMinEditField = uieditfield(app.ModcodSelection, 'numeric');
            app.TimeMinEditField.Editable = 'off';
            app.SpectralEfficiencyEditFieldLabel = uilabel(app.ModcodSelection);
            app.SpectralEfficiencyEditFieldLabel.Text = 'Spectral Efficiency';
            app.SpectralEfficiencyEditField = uieditfield(app.ModcodSelection, 'numeric');
            app.SpectralEfficiencyEditField.Editable = 'off';
            app.MODCODEditFieldLabel = uilabel(app.ModcodSelection);
            app.MODCODEditFieldLabel.Text = 'MODCOD';
            app.MODCODEditField = uieditfield(app.ModcodSelection, 'text');
            app.MODCODEditField.Editable = 'off';
            app.InspectElevationSliderLabel = uilabel(app.PlotTab);
            app.InspectElevationSliderLabel.Tag = 'slider_label';
            app.InspectElevationSliderLabel.Text = 'Inspect Elevation';
            app.InspectElevationSlider = uislider(app.PlotTab);
            app.InspectElevationSlider.ValueChangedFcn = createCallbackFcn(app, @InspectElevationSliderValueChanged, true);
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Spectral Efficiency by Elevation Degree')
            xlabel(app.UIAxes, 'Elevation [deg]')
            ylabel(app.UIAxes, 'Spectral Efficiency [Data Bits/Symbol]')
            app.SheetTab = uitab(app.TabGroup);
            app.SheetTab.Title = 'Sheet';
            app.UITable = uitable(app.SheetTab);
            app.UITable.ColumnName = {'Elevation [deg]'; 'Time [Min]'; 'Modcod'; 'Spectral Efficiency'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.OverallanalysisLabel = uilabel(app.RightPanel);
            app.OverallanalysisLabel.Text = 'Overall analysis';
            app.FileMenu = uimenu(app.LinkBudgetDisplayUIFigure);
            app.FileMenu.Text = 'File';
            app.ExportResultstoCSVMenu = uimenu(app.FileMenu);
            app.ExportResultstoCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
            app.ExportResultstoCSVMenu.Text = 'Export Results to CSV';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            % Show the figure after all components are created
            app.LinkBudgetDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LinkBudgetCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LinkBudgetDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LinkBudgetDisplayUIFigure)
        end
    end
end