classdef visibilityTimeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        visibilityCalculatorUIFigure    matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SatelliteHightKMEditFieldLabel  matlab.ui.control.Label
        SatelliteHightKMEditField       matlab.ui.control.NumericEditField
        MinimalZenithalAngleofSightDegEditFieldLabel  matlab.ui.control.Label
        MinimalZenithalAngleofSightDegEditField  matlab.ui.control.NumericEditField
        VisibiltyTimeMinButton          matlab.ui.control.Button
        visibiltyTime                   matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        visTimeUIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.visibilityCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {277, 277};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {351, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: VisibiltyTimeMinButton
        function VisibiltyTimeMinButtonPushed(app, event)
                       
            % Calculate the visibility time
            hight = app.SatelliteHightKMEditField.Value;
            zenAngle = app.MinimalZenithalAngleofSightDegEditField.Value;
            vTime = visibilityTime(hight, zenAngle);
            app.visibiltyTime.Value = vTime;
            
            %pre allocating and initializing variables
            angle_vec = 0:1:90;
            nAngle = length(angle_vec);
            time_vec = zeros(1,nAngle);
            
            %Calculate the visibility time
            for i = 1:nAngle
                time_vec(i)  = visibilityTime(hight, angle_vec(i));
            end
            
            %Plot the time and angle
            pl = plot(app.visTimeUIAxes, angle_vec, time_vec);
            datatip(pl, zenAngle, vTime);
            xlim(app.visTimeUIAxes, [0,90])
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.visibilityCalculatorUIFigure = uifigure('Visible', 'off');
            app.visibilityCalculatorUIFigure.Name = 'Visibility Time Calculator';
            app.visibilityCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.visibilityCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SatelliteHightKMEditFieldLabel = uilabel(app.LeftPanel);
            app.SatelliteHightKMEditFieldLabel.Text = 'Satellite Hight [KM]';
            app.SatelliteHightKMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SatelliteHightKMEditField.Limits = [0 10000000];
            app.SatelliteHightKMEditField.ValueDisplayFormat = '%8.f';
            app.SatelliteHightKMEditField.Value = 800;
            app.MinimalZenithalAngleofSightDegEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimalZenithalAngleofSightDegEditFieldLabel.Text = 'Minimal Zenithal Angle of Sight [Deg]';
            app.MinimalZenithalAngleofSightDegEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinimalZenithalAngleofSightDegEditField.Limits = [0.001 100];
            app.MinimalZenithalAngleofSightDegEditField.Value = 75;
            app.VisibiltyTimeMinButton = uibutton(app.LeftPanel, 'push');
            app.VisibiltyTimeMinButton.ButtonPushedFcn = createCallbackFcn(app, @VisibiltyTimeMinButtonPushed, true);
            app.VisibiltyTimeMinButton.Text = 'Visibilty Time [Min]';
            app.visibiltyTime = uieditfield(app.LeftPanel, 'numeric');
            app.visibiltyTime.ValueDisplayFormat = '%7.2f';
            app.visibiltyTime.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.visTimeUIAxes = uiaxes(app.RightPanel);
            title(app.visTimeUIAxes, 'Visibilty Time by Zenithal Angle')
            xlabel(app.visTimeUIAxes, 'Angle [Deg]')
            ylabel(app.visTimeUIAxes, 'Time [Min]')
            % Show the figure after all components are created
            app.visibilityCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visibilityTimeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.visibilityCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.visibilityCalculatorUIFigure)
        end
    end
end